classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConvolutionTab                 matlab.ui.container.Tab
        ExecuteConvolutionButton       matlab.ui.control.Button
        LoadImageConvolutionButton     matlab.ui.control.Button
        InputKernelConvolutionLabel    matlab.ui.control.Label
        SpinnerConvolution             matlab.ui.control.Spinner
        MaskSizenxnSpinnerLabel        matlab.ui.control.Label
        OutputImageConvolutionLabel    matlab.ui.control.Label
        InputImageConvolutionLabel     matlab.ui.control.Label
        ImageOutputConvolution         matlab.ui.control.Image
        UITableConvolution             matlab.ui.control.Table
        ImageInputConvolution          matlab.ui.control.Image
        ImageSmoothingSpatialTab       matlab.ui.container.Tab
        SigmaValueEditFieldSmoothing   matlab.ui.control.NumericEditField
        SigmaValueEditFieldLabel       matlab.ui.control.Label
        MaskSizeEditFieldSmoothing     matlab.ui.control.NumericEditField
        MaskSizeEditFieldLabel         matlab.ui.control.Label
        FilterTypeDropDownSmoothing    matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        ExecuteSmoothingButton         matlab.ui.control.Button
        LoadImageSmoothingButton       matlab.ui.control.Button
        OutputImageSmoothingLabel      matlab.ui.control.Label
        InputImageSmoothingLabel       matlab.ui.control.Label
        ImageOutputSmoothing           matlab.ui.control.Image
        ImageInputSmoothing            matlab.ui.control.Image
        ImageSmoothingFrequencyTab     matlab.ui.container.Tab
        FilterTypeDropDownLP           matlab.ui.control.DropDown
        FilterTypeDropDownLabel_2      matlab.ui.control.Label
        OrderEditFieldLP               matlab.ui.control.NumericEditField
        OrderLabel                     matlab.ui.control.Label
        CutoffValueEditFieldLP         matlab.ui.control.NumericEditField
        CutoffFrequencyLabel           matlab.ui.control.Label
        ExecuteLPButton                matlab.ui.control.Button
        LoadImageLPButton              matlab.ui.control.Button
        OutputImageLPLabel             matlab.ui.control.Label
        InputImageLPLabel              matlab.ui.control.Label
        ImageOutputLP                  matlab.ui.control.Image
        ImageInputLP                   matlab.ui.control.Image
        ImageSharpeningFrequencyTab    matlab.ui.container.Tab
        FilterTypeDropDownHP           matlab.ui.control.DropDown
        FilterTypeDropDownLabel_3      matlab.ui.control.Label
        OrderEditFieldHP               matlab.ui.control.NumericEditField
        OrderLabel_2                   matlab.ui.control.Label
        CutoffValueEditFieldHP         matlab.ui.control.NumericEditField
        CutoffFrequencyLabel_2         matlab.ui.control.Label
        ExecuteHPButton                matlab.ui.control.Button
        LoadImageHPButton              matlab.ui.control.Button
        OutputImageHPLabel             matlab.ui.control.Label
        InputImageHPLabel              matlab.ui.control.Label
        ImageOutputHP                  matlab.ui.control.Image
        ImageInputHP                   matlab.ui.control.Image
        ImageBrighteningTab            matlab.ui.container.Tab
        AlphaEditFieldHighBoost        matlab.ui.control.NumericEditField
        OrderLabel_4                   matlab.ui.control.Label
        FilterTypeDropDownHighBoost    matlab.ui.control.DropDown
        FilterTypeDropDownLabel_5      matlab.ui.control.Label
        OrderEditFieldHighBoost        matlab.ui.control.NumericEditField
        OrderLabel_3                   matlab.ui.control.Label
        CutoffValueEditFieldHighBoost  matlab.ui.control.NumericEditField
        CutoffFrequencyLabel_3         matlab.ui.control.Label
        ExecuteHighBoostButton         matlab.ui.control.Button
        LoadImageHighBoostButton       matlab.ui.control.Button
        OutputImageHighBoostLabel      matlab.ui.control.Label
        InputImageHighBoostLabel       matlab.ui.control.Label
        ImageOutputHighBoost           matlab.ui.control.Image
        ImageInputHighBoost            matlab.ui.control.Image
        SaltPepperRemovalTab           matlab.ui.container.Tab
        dValueEditField                matlab.ui.control.NumericEditField
        QvaluecontraharmonicLabel_2    matlab.ui.control.Label
        QValueEditField                matlab.ui.control.NumericEditField
        QvaluecontraharmonicLabel      matlab.ui.control.Label
        WindowSizeEditField            matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeDropDownLabel_4      matlab.ui.control.Label
        DensityVarianceEditField       matlab.ui.control.NumericEditField
        DensityVarianceEditFieldLabel  matlab.ui.control.Label
        NoiseTypeDropDown              matlab.ui.control.DropDown
        NoiseTypeDropDownLabel         matlab.ui.control.Label
        ExecuteNoiseButton             matlab.ui.control.Button
        NoiseImageLabel                matlab.ui.control.Label
        ImageNoise                     matlab.ui.control.Image
        ExecuteOutputNoiseButton       matlab.ui.control.Button
        LoadImageInputNoiseButton      matlab.ui.control.Button
        OutputImageNoiseLabel          matlab.ui.control.Label
        InputImageNoiseLabel           matlab.ui.control.Label
        ImageOutputNoise               matlab.ui.control.Image
        ImageInputNoise                matlab.ui.control.Image
        PeriodicNoiseRemovalTab        matlab.ui.container.Tab
        NoiseImageLabel_3              matlab.ui.control.Label
        ImageOutputFreq                matlab.ui.control.Image
        InputImageNoiseLabel_3         matlab.ui.control.Label
        ImageOutputPeriodic            matlab.ui.control.Image
        wValueEditField                matlab.ui.control.NumericEditField
        QvaluecontraharmonicLabel_5    matlab.ui.control.Label
        D0EditField                    matlab.ui.control.NumericEditField
        D0EditFieldLabel               matlab.ui.control.Label
        ImageInputFreqLabel            matlab.ui.control.Label
        ImageInputFreq                 matlab.ui.control.Image
        ExecuteOutputPeriodicButton    matlab.ui.control.Button
        LoadImageInputPeriodicButton   matlab.ui.control.Button
        InputImagePeriodicLabel        matlab.ui.control.Label
        ImageInputPeriodic             matlab.ui.control.Image
        WienerMotionBlurTab            matlab.ui.container.Tab
        LengthEditFieldBlur            matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        NSRValueEditFieldBlur          matlab.ui.control.NumericEditField
        QvaluecontraharmonicLabel_4    matlab.ui.control.Label
        ThetaEditFieldBlur             matlab.ui.control.NumericEditField
        ThetaEditFieldLabel            matlab.ui.control.Label
        ExecuteBlurButton              matlab.ui.control.Button
        BlurredImageLabel              matlab.ui.control.Label
        ImageBlur                      matlab.ui.control.Image
        ExecuteOutputBlurButton        matlab.ui.control.Button
        LoadImageInputBlurButton       matlab.ui.control.Button
        OutputImageBlurLabel           matlab.ui.control.Label
        InputImageBlurLabel            matlab.ui.control.Label
        ImageOutputBlur                matlab.ui.control.Image
        ImageInputBlur                 matlab.ui.control.Image
        DeblurringTab                  matlab.ui.container.Tab
        sigmaValueEditFieldDeblur      matlab.ui.control.NumericEditField
        CutoffFrequencyLabel_4         matlab.ui.control.Label
        ExecuteDeblurButton            matlab.ui.control.Button
        LoadImageDeblur                matlab.ui.control.Button
        OutputImageHighBoostLabel_2    matlab.ui.control.Label
        InputImageHighBoostLabel_2     matlab.ui.control.Label
        ImageOutputDeblur              matlab.ui.control.Image
        ImageInputDeblur               matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get the screen size
            screenSize = get(groot, 'ScreenSize');
            
            % Set UIFigure position to fullscreen
            app.UIFigure.Position = [screenSize(1) screenSize(2) screenSize(3) screenSize(4)];

            app.UITableConvolution.Data = [0 0 0; 0 0 0; 0 0 0];
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % Get current size of the UIFigure
            figWidth = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            
            % Update the TabGroup to match UIFigure's size
            app.TabGroup.Position = [1, 1, figWidth, figHeight];
        end

        % Button pushed function: LoadImageConvolutionButton
        function LoadImageConvolutionButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputConvolution.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Value changed function: SpinnerConvolution
        function SpinnerConvolutionValueChanged(app, event)
            % Get the current spinner value
            value = app.SpinnerConvolution.Value;
            
            % Create a matrix of zeros with size (value x value)
            newData = zeros(value, value);
            
            % Update the UITableConvolution with the new data
            app.UITableConvolution.Data = newData;
        end

        % Button pushed function: ExecuteConvolutionButton
        function ExecuteConvolutionButtonPushed(app, event)
            % Retrieve the image path from ImageInputConvolution
            imagePath = app.ImageInputConvolution.ImageSource;
            
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
            
            % Ensure the image is of valid type (e.g., convert double to uint8 if needed)
            if ~isa(imageMatrix, 'uint8')
                imageMatrix = im2uint8(imageMatrix);
            end
            
            % Get the convolution mask from UITableConvolution
            mask = app.UITableConvolution.Data;
            
            % Ensure the mask is a valid square matrix
            [rows, cols] = size(mask);
            if rows ~= cols
                uialert(app.UIFigure, 'The convolution mask must be square (e.g., 3x3, 5x5).', 'Error');
                return;
            end
            
            % Apply the convolution function
            resultImage = convolution(imageMatrix, mask);
            
            % Convert resultImage to uint8 if necessary
            if ~isa(resultImage, 'uint8')
                resultImage = im2uint8(resultImage);
            end

             % Display the result in the ImageOutputConvolution component
            if size(resultImage, 3) == 1
                % If it's grayscale, convert to RGB for display
                resultImage = cat(3, resultImage, resultImage, resultImage);
            end
            
            app.ImageOutputConvolution.ImageSource = resultImage; % Correctly set the ImageSource
        end

        % Value changed function: FilterTypeDropDownSmoothing
        function FilterTypeDropDownSmoothingValueChanged(app, event)

        end

        % Callback function
        function ImageSmoothingTabButtonDown(app, event)

        end

        % Button pushed function: LoadImageSmoothingButton
        function LoadImageSmoothingButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputSmoothing.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteSmoothingButton
        function ExecuteSmoothingButtonPushed(app, event)
            % Retrieve the image path from ImageInputSmoothing
            imagePath = app.ImageInputSmoothing.ImageSource; 
            
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
            
            % Get the mask size (n) from the edit field
            n = app.MaskSizeEditFieldSmoothing.Value;
            sigma = app.SigmaValueEditFieldSmoothing.Value;
            
            % Ensure n is a positive odd integer
            if mod(n, 2) == 0 || n < 1
                uialert(app.UIFigure, 'Mask size must be a positive odd integer.', 'Error');
                return;
            end
            
            if strcmp(app.FilterTypeDropDownSmoothing.Value, 'Mean Filter')
                % Apply the mean filter
                meanFilteredImage = meanFilter(imageMatrix, n);
                
                % Display the mean filtered image in the output UI element
                if size(meanFilteredImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    meanFilteredImage = cat(3, meanFilteredImage, meanFilteredImage, meanFilteredImage);
                end
                
                app.ImageOutputSmoothing.ImageSource = meanFilteredImage;
            else
                % Apply the gaussian filter
                gaussianFilteredImage = gaussianFilter(imageMatrix, n, sigma);
                
                % Display the mean filtered image in the output UI element
                if size(gaussianFilteredImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    gaussianFilteredImage = cat(3, gaussianFilteredImage, gaussianFilteredImage, gaussianFilteredImage);
                end
                
                app.ImageOutputSmoothing.ImageSource = gaussianFilteredImage;
            end
        end

        % Button pushed function: LoadImageLPButton
        function LoadImageLPButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputLP.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteLPButton
        function ExecuteLPButtonPushed(app, event)
            % Retrieve the image path from ImageInputSmoothing
            imagePath = app.ImageInputLP.ImageSource; 
            
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
            
            % Get the mask size (n) from the edit field
            D0 = app.CutoffValueEditFieldLP.Value;
            n = app.OrderEditFieldLP.Value;
            
            dropdownValue = app.FilterTypeDropDownLP.Value;
            
            if strcmp(dropdownValue, 'ILPF')
                if ndims(imageMatrix) == 3
                    redChan = ILPF(imageMatrix(:,:,1), D0);
                    greenChan = ILPF(imageMatrix(:,:,2), D0);
                    blueChan = ILPF(imageMatrix(:,:,3), D0);
                    app.ImageOutputLP.ImageSource = cat(3, redChan, greenChan, blueChan);
                else
                    app.ImageOutputLP.ImageSource = cat(3, ILPF(imageMatrix, D0), ILPF(imageMatrix, D0), ILPF(imageMatrix, D0));
                end

            elseif strcmp(dropdownValue, 'GLPF')
                if ndims(imageMatrix) == 3
                    redChan = GLPF(imageMatrix(:,:,1), D0);
                    greenChan = GLPF(imageMatrix(:,:,2), D0);
                    blueChan = GLPF(imageMatrix(:,:,3), D0);
                    app.ImageOutputLP.ImageSource = cat(3, redChan, greenChan, blueChan);
                else
                    app.ImageOutputLP.ImageSource = cat(3, GLPF(imageMatrix, D0), GLPF(imageMatrix, D0), GLPF(imageMatrix, D0));
                end

            else
                if ndims(imageMatrix) == 3
                    redChan = BLPF(imageMatrix(:,:,1), D0, n);
                    greenChan = BLPF(imageMatrix(:,:,2), D0, n);
                    blueChan = BLPF(imageMatrix(:,:,3), D0, n);
                    app.ImageOutputLP.ImageSource = cat(3, redChan, greenChan, blueChan);
                else
                    app.ImageOutputLP.ImageSource = cat(3, BLPF(imageMatrix, D0, n), BLPF(imageMatrix, D0, n), BLPF(imageMatrix, D0, n));
                end
            end
        end

        % Button pushed function: LoadImageHPButton
        function LoadImageHPButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputHP.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteHPButton
        function ExecuteHPButtonPushed(app, event)
            % Retrieve the image path from ImageInputSmoothing
            imagePath = app.ImageInputHP.ImageSource; 
            
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
            
            % Get the mask size (n) from the edit field
            D0 = app.CutoffValueEditFieldHP.Value;
            n = app.OrderEditFieldHP.Value;
            
            dropdownValue = app.FilterTypeDropDownHP.Value;
            
            if strcmp(dropdownValue, 'IHPF')
                if ndims(imageMatrix) == 3
                    redChan = IHPF(imageMatrix(:,:,1), D0);
                    greenChan = IHPF(imageMatrix(:,:,2), D0);
                    blueChan = IHPF(imageMatrix(:,:,3), D0);
                    app.ImageOutputHP.ImageSource = cat(3, redChan, greenChan, blueChan);
                else
                    app.ImageOutputHP.ImageSource = cat(3, IHPF(imageMatrix, D0), IHPF(imageMatrix, D0), IHPF(imageMatrix, D0));
                end

            elseif strcmp(dropdownValue, 'GHPF')
                if ndims(imageMatrix) == 3
                    redChan = GHPF(imageMatrix(:,:,1), D0);
                    greenChan = GHPF(imageMatrix(:,:,2), D0);
                    blueChan = GHPF(imageMatrix(:,:,3), D0);
                    app.ImageOutputHP.ImageSource = cat(3, redChan, greenChan, blueChan);
                else
                    app.ImageOutputHP.ImageSource = cat(3, GHPF(imageMatrix, D0), GHPF(imageMatrix, D0), GHPF(imageMatrix, D0));
                end

            else
                if ndims(imageMatrix) == 3
                    redChan = BHPF(imageMatrix(:,:,1), D0, n);
                    greenChan = BHPF(imageMatrix(:,:,2), D0, n);
                    blueChan = BHPF(imageMatrix(:,:,3), D0, n);
                    app.ImageOutputHP.ImageSource = cat(3, redChan, greenChan, blueChan);
                else
                    app.ImageOutputHP.ImageSource = cat(3, BHPF(imageMatrix, D0, n), BHPF(imageMatrix, D0, n), BHPF(imageMatrix, D0, n));
                end
            end
        end

        % Button pushed function: LoadImageInputNoiseButton
        function LoadImageInputNoiseButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputNoise.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteNoiseButton
        function ExecuteNoiseButtonPushed(app, event)
            % Retrieve the image from ImageInputNoise
            img_path = app.ImageInputNoise.ImageSource;  % Get the image path
            originalImage = imread(img_path);  % Read the image file
            
            % Check if the image is loaded successfully
            if isempty(originalImage)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Get noise type and density from app components
            noiseType = app.NoiseTypeDropDown.Value;  % Get selected noise type
            density = app.DensityVarianceEditField.Value; % Get density input (should be a scalar)
            
            % Convert image to double for processing
            imageMatrix = im2double(originalImage);
            
            % Add the selected type of noise
            switch noiseType
                case 'Salt & Pepper'
                    noisyImage = imnoise(imageMatrix, 'salt & pepper', density);
                case 'Gaussian'
                    noisyImage = imnoise(imageMatrix, 'gaussian', 0, density); % 0 is the mean for Gaussian noise
                otherwise
                    uialert(app.UIFigure, 'Invalid noise type selected.', 'Error');
                    return;
            end

            % Display the mean filtered image in the output UI element
            if size(noisyImage, 3) == 1
                % If the result is grayscale, convert it to RGB
                noisyImage = cat(3, noisyImage, noisyImage, noisyImage);
            end
            
            app.ImageNoise.ImageSource = noisyImage;
        end

        % Button pushed function: ExecuteOutputNoiseButton
        function ExecuteOutputNoiseButtonPushed(app, event)
            % Retrieve the image from ImageNoise
            img = app.ImageNoise.ImageSource;  % Get the image data directly
            if isempty(img)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
        
            % Check if the image is loaded as expected
            if ischar(img) || isstring(img)
                originalImage = imread(img);  % Read the image file if img is a path
            else
                originalImage = img;  % Assume img is already in matrix form
            end
        
            % Get filter type, window size, Q value, and d value from app components
            filterType = app.FilterTypeDropDown.Value;
            windowSize = app.WindowSizeEditField.Value;
            QValue = app.QValueEditField.Value;
            dValue = app.dValueEditField.Value;
        
            % Convert image to double for processing
            imageMatrix = im2double(originalImage);
        
            switch filterType
                case 'Min Filter'
                    noiseRemoval = minFilter(imageMatrix, windowSize);
                case 'Max Filter'
                    noiseRemoval = maxFilter(imageMatrix, windowSize);
                case 'Median Filter'
                    noiseRemoval = medianFilter(imageMatrix, windowSize);
                case 'Arithmetic Mean Filter'
                    noiseRemoval = arithmeticMeanFilter(imageMatrix, windowSize);
                case 'Geometric Mean Filter'
                    noiseRemoval = geometricMeanFilter(imageMatrix, windowSize);
                case 'Harmonic Mean Filter'
                    noiseRemoval = harmonicMeanFilter(imageMatrix, windowSize);
                case 'Contraharmonic Mean Filter'
                    noiseRemoval = contraharmonicMeanFilter(imageMatrix, windowSize, QValue);
                case 'Midpoint Filter'
                    noiseRemoval = midpointFilter(imageMatrix, windowSize);
                case 'Alpha-trimmed Filter'
                    noiseRemoval = alphaTrimmedMeanFilter(imageMatrix, windowSize, dValue);
                otherwise
                    uialert(app.UIFigure, 'Invalid filter type selected.', 'Error');
                    return;
            end
        
            % Display the mean filtered image in the output UI element
            if size(noiseRemoval, 3) == 1
                % If the result is grayscale, convert it to RGB
                noiseRemoval = cat(3, noiseRemoval, noiseRemoval, noiseRemoval);
            end
            
            % Convert the result to uint8 for displaying as an image
            app.ImageOutputNoise.ImageSource = im2uint8(noiseRemoval);
        end

        % Button pushed function: LoadImageInputBlurButton
        function LoadImageInputBlurButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputBlur.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteOutputBlurButton
        function ExecuteOutputBlurButtonPushed(app, event)
            % Ensure the input image path is loaded
            if isempty(app.ImageBlur.ImageSource)
                uialert(app.UIFigure, 'Please blur an image first.', 'Error');
                return;
            end
        
            % Read the image from the ImageSource
            I = im2double(app.ImageBlur.ImageSource); % Convert image to double for processing

            % Get deblur parameters
            len = app.LengthEditFieldBlur.Value;
            theta = app.ThetaEditFieldBlur.Value;
            NSR = app.NSRValueEditFieldBlur.Value;
        
            % Generate PSF for motion blur
            PSF = fspecial('motion', len, theta);
        
            % Apply Wiener deconvolution
            DeblurredImage = wiener(I, PSF, NSR);
        
            % Display the deblurred image in the output UI element
            app.ImageOutputBlur.ImageSource = im2uint8(DeblurredImage); % Convert to uint8 for compatibility
        end

        % Button pushed function: ExecuteBlurButton
        function ExecuteBlurButtonPushed(app, event)
            % Ensure the input image path is loaded
            if isempty(app.ImageInputBlur.ImageSource)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
        
            % Read the image from the ImageSource
            I = imread(app.ImageInputBlur.ImageSource);
            I = im2double(I); % Convert image to double for processing

            % Apply motion blur
            blurredImage = motionBlur(I, app.LengthEditFieldBlur.Value, app.ThetaEditFieldBlur.Value);
        
            % Convert the blurred image to RGB if it is grayscale
            if size(blurredImage, 3) == 1
                blurredImage = cat(3, blurredImage, blurredImage, blurredImage);
            end
            
            % Display the blurred image in the output UI element
            app.ImageBlur.ImageSource = im2uint8(blurredImage);  % Convert to uint8 for compatibility
        end

        % Button pushed function: LoadImageHighBoostButton
        function LoadImageHighBoostButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputHighBoost.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteHighBoostButton
        function ExecuteHighBoostButtonPushed(app, event)
            % Retrieve the image path from ImageInputSmoothing
            imagePath = app.ImageInputHighBoost.ImageSource; 
        
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
        
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
        
            % Get the mask size (D0), order (n), and alpha from the edit fields
            D0 = app.CutoffValueEditFieldHighBoost.Value;
            n = app.OrderEditFieldHighBoost.Value;
            alpha = app.AlphaEditFieldHighBoost.Value;
        
            % Determine the selected high-pass filter type
            dropdownValue = app.FilterTypeDropDownHP.Value;
        
            % Apply the selected high-pass filter
            if strcmp(dropdownValue, 'IHPF')
                if ndims(imageMatrix) == 3
                    redChan = IHPF(imageMatrix(:,:,1), D0);
                    greenChan = IHPF(imageMatrix(:,:,2), D0);
                    blueChan = IHPF(imageMatrix(:,:,3), D0);
                    highPassFiltered = cat(3, redChan, greenChan, blueChan);
                else
                    highPassFiltered = cat(3, IHPF(imageMatrix, D0), IHPF(imageMatrix, D0), IHPF(imageMatrix, D0));
                end
            elseif strcmp(dropdownValue, 'GHPF')
                if ndims(imageMatrix) == 3
                    redChan = GHPF(imageMatrix(:,:,1), D0);
                    greenChan = GHPF(imageMatrix(:,:,2), D0);
                    blueChan = GHPF(imageMatrix(:,:,3), D0);
                    highPassFiltered = cat(3, redChan, greenChan, blueChan);
                else
                    highPassFiltered = cat(3, GHPF(imageMatrix, D0), GHPF(imageMatrix, D0), GHPF(imageMatrix, D0));
                end
            else
                if ndims(imageMatrix) == 3
                    redChan = BHPF(imageMatrix(:,:,1), D0, n);
                    greenChan = BHPF(imageMatrix(:,:,2), D0, n);
                    blueChan = BHPF(imageMatrix(:,:,3), D0, n);
                    highPassFiltered.ImageSource = cat(3, redChan, greenChan, blueChan);
                else
                    highPassFiltered = cat(3, BHPF(imageMatrix, D0, n), BHPF(imageMatrix, D0, n), BHPF(imageMatrix, D0, n));
                end
            end
        
            % Convert the high-pass filtered image and original image to double precision
            highPassFiltered = im2double(highPassFiltered);
            originalImage = im2double(imageMatrix);
        
            % Ensure the high-pass filtered image has the same size as the original image
            if ~isequal(size(highPassFiltered), size(originalImage))
                highPassFiltered = imresize(highPassFiltered, [size(originalImage, 1), size(originalImage, 2)]);
            end
        
            % Compute the high-boosted image: (alpha - 1) * original + high-pass component
            resultImage = (alpha - 1) * originalImage + highPassFiltered;
        
            % Display the high-boost filtered image in the output UI element
            if size(resultImage, 3) == 1
                resultImage = cat(3, resultImage, resultImage, resultImage); % Convert grayscale to RGB if necessary
            end
        
            % Convert result image to uint8 for display if needed
            app.ImageOutputHighBoost.ImageSource = im2uint8(resultImage);
        end

        % Button pushed function: LoadImageInputPeriodicButton
        function LoadImageInputPeriodicButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputPeriodic.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteOutputPeriodicButton
        function ExecuteOutputPeriodicButtonPushed(app, event)
            % Check if there is an input image loaded
            if isempty(app.ImageInputPeriodic)
                return;
            end
        
            % Retrieve filter parameters
            D0 = app.D0EditField.Value;
            w = app.wValueEditField.Value;
        
            % Read the image
            originalImage = imread(app.ImageInputPeriodic.ImageSource);
            
            % Check if the image is grayscale (2D) or RGB (3D)
            if size(originalImage, 3) == 1
                % If grayscale, convert to RGB
                originalImage = cat(3, originalImage, originalImage, originalImage);
            end
        
            % Apply band-reject filter
            [imageFrequency, denoisedImage, denoisedImageFrequency] = bandRejectFilter(originalImage, D0, w);
        
            % Convert frequency and denoised images to RGB if they are grayscale
            if ismatrix(imageFrequency)
                imageFrequency = cat(3, imageFrequency, imageFrequency, imageFrequency);
            end
            if ismatrix(denoisedImage)
                denoisedImage = cat(3, denoisedImage, denoisedImage, denoisedImage);
            end
            if ismatrix(denoisedImageFrequency)
                denoisedImageFrequency = cat(3, denoisedImageFrequency, denoisedImageFrequency, denoisedImageFrequency);
            end
        
            % Set the image sources for display
            app.ImageInputFreq.ImageSource = imageFrequency;
            app.ImageOutputPeriodic.ImageSource = denoisedImage;
            app.ImageOutputFreq.ImageSource = denoisedImageFrequency;
        end

        % Button pushed function: LoadImageDeblur
        function LoadImageDeblurButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputDeblur.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteDeblurButton
        function ExecuteDeblurButtonPushed(app, event)
            image = app.ImageInputDeblur;

            if isempty(image)
                return;
            end

            % Do deblur
            estimatedPSF = fspecial('gaussian', [10, 10], app.sigmaValueEditFieldDeblur.Value);
            deblurredImage = deconvlucy(imread(image.ImageSource), estimatedPSF, 15);

            % Update image
            if ndims(deblurredImage) == 3
                app.ImageOutputDeblur.ImageSource = deblurredImage;
            else
                app.ImageOutputDeblur.ImageSource = cat(3, deblurredImage, deblurredImage, deblurredImage);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.ImageInputConvolution = uiimage(app.ConvolutionTab);
            app.UITableConvolution = uitable(app.ConvolutionTab);
            app.UITableConvolution.ColumnName = '';
            app.UITableConvolution.RowName = {};
            app.UITableConvolution.ColumnEditable = true;
            app.ImageOutputConvolution = uiimage(app.ConvolutionTab);
            app.InputImageConvolutionLabel = uilabel(app.ConvolutionTab);
            app.InputImageConvolutionLabel.Text = 'Input Image';
            app.OutputImageConvolutionLabel = uilabel(app.ConvolutionTab);
            app.OutputImageConvolutionLabel.Text = 'Output Image';
            app.MaskSizenxnSpinnerLabel = uilabel(app.ConvolutionTab);
            app.MaskSizenxnSpinnerLabel.Text = 'Mask Size (n x n)';
            app.SpinnerConvolution = uispinner(app.ConvolutionTab);
            app.SpinnerConvolution.Limits = [1 Inf];
            app.SpinnerConvolution.ValueChangedFcn = createCallbackFcn(app, @SpinnerConvolutionValueChanged, true);
            app.SpinnerConvolution.Value = 3;
            app.InputKernelConvolutionLabel = uilabel(app.ConvolutionTab);
            app.InputKernelConvolutionLabel.Text = 'Input Kernel';
            app.LoadImageConvolutionButton = uibutton(app.ConvolutionTab, 'push');
            app.LoadImageConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageConvolutionButtonPushed, true);
            app.LoadImageConvolutionButton.Text = 'Load Image';
            app.ExecuteConvolutionButton = uibutton(app.ConvolutionTab, 'push');
            app.ExecuteConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteConvolutionButtonPushed, true);
            app.ExecuteConvolutionButton.Text = 'Execute';
            app.ImageSmoothingSpatialTab = uitab(app.TabGroup);
            app.ImageSmoothingSpatialTab.Title = 'Image Smoothing Spatial';
            app.ImageInputSmoothing = uiimage(app.ImageSmoothingSpatialTab);
            app.ImageOutputSmoothing = uiimage(app.ImageSmoothingSpatialTab);
            app.InputImageSmoothingLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.InputImageSmoothingLabel.Text = 'Input Image';
            app.OutputImageSmoothingLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.OutputImageSmoothingLabel.Text = 'Output Image';
            app.LoadImageSmoothingButton = uibutton(app.ImageSmoothingSpatialTab, 'push');
            app.LoadImageSmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageSmoothingButtonPushed, true);
            app.LoadImageSmoothingButton.Text = 'Load Image';
            app.ExecuteSmoothingButton = uibutton(app.ImageSmoothingSpatialTab, 'push');
            app.ExecuteSmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteSmoothingButtonPushed, true);
            app.ExecuteSmoothingButton.Text = 'Execute';
            app.FilterTypeDropDownLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDownSmoothing = uidropdown(app.ImageSmoothingSpatialTab);
            app.FilterTypeDropDownSmoothing.Items = {'Mean Filter', 'Gaussian Filter'};
            app.FilterTypeDropDownSmoothing.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownSmoothingValueChanged, true);
            app.FilterTypeDropDownSmoothing.Value = 'Mean Filter';
            app.MaskSizeEditFieldLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.MaskSizeEditFieldLabel.Text = 'Mask Size';
            app.MaskSizeEditFieldSmoothing = uieditfield(app.ImageSmoothingSpatialTab, 'numeric');
            app.MaskSizeEditFieldSmoothing.Limits = [1 Inf];
            app.MaskSizeEditFieldSmoothing.Value = 1;
            app.SigmaValueEditFieldLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.SigmaValueEditFieldLabel.Text = 'Sigma Value';
            app.SigmaValueEditFieldSmoothing = uieditfield(app.ImageSmoothingSpatialTab, 'numeric');
            app.SigmaValueEditFieldSmoothing.Value = 1;
            app.ImageSmoothingFrequencyTab = uitab(app.TabGroup);
            app.ImageSmoothingFrequencyTab.Title = 'Image Smoothing Frequency';
            app.ImageInputLP = uiimage(app.ImageSmoothingFrequencyTab);
            app.ImageOutputLP = uiimage(app.ImageSmoothingFrequencyTab);
            app.InputImageLPLabel = uilabel(app.ImageSmoothingFrequencyTab);
            app.InputImageLPLabel.Text = 'Input Image';
            app.OutputImageLPLabel = uilabel(app.ImageSmoothingFrequencyTab);
            app.OutputImageLPLabel.Text = 'Output Image';
            app.LoadImageLPButton = uibutton(app.ImageSmoothingFrequencyTab, 'push');
            app.LoadImageLPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageLPButtonPushed, true);
            app.LoadImageLPButton.Text = 'Load Image';
            app.ExecuteLPButton = uibutton(app.ImageSmoothingFrequencyTab, 'push');
            app.ExecuteLPButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteLPButtonPushed, true);
            app.ExecuteLPButton.Text = 'Execute';
            app.CutoffFrequencyLabel = uilabel(app.ImageSmoothingFrequencyTab);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.CutoffValueEditFieldLP = uieditfield(app.ImageSmoothingFrequencyTab, 'numeric');
            app.CutoffValueEditFieldLP.Value = 1;
            app.OrderLabel = uilabel(app.ImageSmoothingFrequencyTab);
            app.OrderLabel.Text = 'Order';
            app.OrderEditFieldLP = uieditfield(app.ImageSmoothingFrequencyTab, 'numeric');
            app.OrderEditFieldLP.Value = 1;
            app.FilterTypeDropDownLabel_2 = uilabel(app.ImageSmoothingFrequencyTab);
            app.FilterTypeDropDownLabel_2.Text = 'Filter Type';
            app.FilterTypeDropDownLP = uidropdown(app.ImageSmoothingFrequencyTab);
            app.FilterTypeDropDownLP.Items = {'ILPF', 'GLPF', 'BLPF'};
            app.FilterTypeDropDownLP.Value = 'ILPF';
            app.ImageSharpeningFrequencyTab = uitab(app.TabGroup);
            app.ImageSharpeningFrequencyTab.Title = 'Image Sharpening Frequency';
            app.ImageInputHP = uiimage(app.ImageSharpeningFrequencyTab);
            app.ImageOutputHP = uiimage(app.ImageSharpeningFrequencyTab);
            app.InputImageHPLabel = uilabel(app.ImageSharpeningFrequencyTab);
            app.InputImageHPLabel.Text = 'Input Image';
            app.OutputImageHPLabel = uilabel(app.ImageSharpeningFrequencyTab);
            app.OutputImageHPLabel.Text = 'Output Image';
            app.LoadImageHPButton = uibutton(app.ImageSharpeningFrequencyTab, 'push');
            app.LoadImageHPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageHPButtonPushed, true);
            app.LoadImageHPButton.Text = 'Load Image';
            app.ExecuteHPButton = uibutton(app.ImageSharpeningFrequencyTab, 'push');
            app.ExecuteHPButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteHPButtonPushed, true);
            app.ExecuteHPButton.Text = 'Execute';
            app.CutoffFrequencyLabel_2 = uilabel(app.ImageSharpeningFrequencyTab);
            app.CutoffFrequencyLabel_2.Text = 'Cutoff Frequency';
            app.CutoffValueEditFieldHP = uieditfield(app.ImageSharpeningFrequencyTab, 'numeric');
            app.CutoffValueEditFieldHP.Value = 1;
            app.OrderLabel_2 = uilabel(app.ImageSharpeningFrequencyTab);
            app.OrderLabel_2.Text = 'Order';
            app.OrderEditFieldHP = uieditfield(app.ImageSharpeningFrequencyTab, 'numeric');
            app.OrderEditFieldHP.Value = 1;
            app.FilterTypeDropDownLabel_3 = uilabel(app.ImageSharpeningFrequencyTab);
            app.FilterTypeDropDownLabel_3.Text = 'Filter Type';
            app.FilterTypeDropDownHP = uidropdown(app.ImageSharpeningFrequencyTab);
            app.FilterTypeDropDownHP.Items = {'IHPF', 'GHPF', 'BHPF'};
            app.FilterTypeDropDownHP.Value = 'IHPF';
            app.ImageBrighteningTab = uitab(app.TabGroup);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.ImageInputHighBoost = uiimage(app.ImageBrighteningTab);
            app.ImageOutputHighBoost = uiimage(app.ImageBrighteningTab);
            app.InputImageHighBoostLabel = uilabel(app.ImageBrighteningTab);
            app.InputImageHighBoostLabel.Text = 'Input Image';
            app.OutputImageHighBoostLabel = uilabel(app.ImageBrighteningTab);
            app.OutputImageHighBoostLabel.Text = 'Output Image';
            app.LoadImageHighBoostButton = uibutton(app.ImageBrighteningTab, 'push');
            app.LoadImageHighBoostButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageHighBoostButtonPushed, true);
            app.LoadImageHighBoostButton.Text = 'Load Image';
            app.ExecuteHighBoostButton = uibutton(app.ImageBrighteningTab, 'push');
            app.ExecuteHighBoostButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteHighBoostButtonPushed, true);
            app.ExecuteHighBoostButton.Text = 'Execute';
            app.CutoffFrequencyLabel_3 = uilabel(app.ImageBrighteningTab);
            app.CutoffFrequencyLabel_3.Text = 'Cutoff Frequency';
            app.CutoffValueEditFieldHighBoost = uieditfield(app.ImageBrighteningTab, 'numeric');
            app.CutoffValueEditFieldHighBoost.Value = 1;
            app.OrderLabel_3 = uilabel(app.ImageBrighteningTab);
            app.OrderLabel_3.Text = 'Order';
            app.OrderEditFieldHighBoost = uieditfield(app.ImageBrighteningTab, 'numeric');
            app.OrderEditFieldHighBoost.Value = 1;
            app.FilterTypeDropDownLabel_5 = uilabel(app.ImageBrighteningTab);
            app.FilterTypeDropDownLabel_5.Text = 'Filter Type';
            app.FilterTypeDropDownHighBoost = uidropdown(app.ImageBrighteningTab);
            app.FilterTypeDropDownHighBoost.Items = {'IHPF', 'GHPF', 'BHPF'};
            app.FilterTypeDropDownHighBoost.Value = 'IHPF';
            app.OrderLabel_4 = uilabel(app.ImageBrighteningTab);
            app.OrderLabel_4.Text = 'alpha';
            app.AlphaEditFieldHighBoost = uieditfield(app.ImageBrighteningTab, 'numeric');
            app.AlphaEditFieldHighBoost.Value = 1;
            app.SaltPepperRemovalTab = uitab(app.TabGroup);
            app.SaltPepperRemovalTab.Title = 'Salt & Pepper Removal';
            app.ImageInputNoise = uiimage(app.SaltPepperRemovalTab);
            app.ImageOutputNoise = uiimage(app.SaltPepperRemovalTab);
            app.InputImageNoiseLabel = uilabel(app.SaltPepperRemovalTab);
            app.InputImageNoiseLabel.Text = 'Input Image';
            app.OutputImageNoiseLabel = uilabel(app.SaltPepperRemovalTab);
            app.OutputImageNoiseLabel.Text = 'Output Image';
            app.LoadImageInputNoiseButton = uibutton(app.SaltPepperRemovalTab, 'push');
            app.LoadImageInputNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageInputNoiseButtonPushed, true);
            app.LoadImageInputNoiseButton.Text = 'Load Image';
            app.ExecuteOutputNoiseButton = uibutton(app.SaltPepperRemovalTab, 'push');
            app.ExecuteOutputNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteOutputNoiseButtonPushed, true);
            app.ExecuteOutputNoiseButton.Text = 'Execute';
            app.ImageNoise = uiimage(app.SaltPepperRemovalTab);
            app.NoiseImageLabel = uilabel(app.SaltPepperRemovalTab);
            app.NoiseImageLabel.Text = 'Noisy Image';
            app.ExecuteNoiseButton = uibutton(app.SaltPepperRemovalTab, 'push');
            app.ExecuteNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteNoiseButtonPushed, true);
            app.ExecuteNoiseButton.Text = 'Add Noise';
            app.NoiseTypeDropDownLabel = uilabel(app.SaltPepperRemovalTab);
            app.NoiseTypeDropDownLabel.Text = 'Noise Type';
            app.NoiseTypeDropDown = uidropdown(app.SaltPepperRemovalTab);
            app.NoiseTypeDropDown.Items = {'Salt & Pepper', 'Gaussian'};
            app.NoiseTypeDropDown.Value = 'Salt & Pepper';
            app.DensityVarianceEditFieldLabel = uilabel(app.SaltPepperRemovalTab);
            app.DensityVarianceEditFieldLabel.Text = 'Density / Variance';
            app.DensityVarianceEditField = uieditfield(app.SaltPepperRemovalTab, 'numeric');
            app.FilterTypeDropDownLabel_4 = uilabel(app.SaltPepperRemovalTab);
            app.FilterTypeDropDownLabel_4.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.SaltPepperRemovalTab);
            app.FilterTypeDropDown.Items = {'Min Filter', 'Max Filter', 'Median Filter', 'Arithmetic Mean Filter', 'Geometric Mean Filter', 'Harmonic Mean Filter', 'Contraharmonic Mean Filter', 'Midpoint Filter', 'Alpha-trimmed Filter'};
            app.FilterTypeDropDown.Value = 'Min Filter';
            app.WindowSizeEditFieldLabel = uilabel(app.SaltPepperRemovalTab);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.SaltPepperRemovalTab, 'numeric');
            app.WindowSizeEditField.Limits = [1 Inf];
            app.WindowSizeEditField.Value = 1;
            app.QvaluecontraharmonicLabel = uilabel(app.SaltPepperRemovalTab);
            app.QvaluecontraharmonicLabel.Text = 'Q value (contraharmonic)';
            app.QValueEditField = uieditfield(app.SaltPepperRemovalTab, 'numeric');
            app.QValueEditField.Value = 1;
            app.QvaluecontraharmonicLabel_2 = uilabel(app.SaltPepperRemovalTab);
            app.QvaluecontraharmonicLabel_2.Text = 'd value (alpha-trimmed)';
            app.dValueEditField = uieditfield(app.SaltPepperRemovalTab, 'numeric');
            app.dValueEditField.Value = 1;
            app.PeriodicNoiseRemovalTab = uitab(app.TabGroup);
            app.PeriodicNoiseRemovalTab.Title = 'Periodic Noise Removal';
            app.ImageInputPeriodic = uiimage(app.PeriodicNoiseRemovalTab);
            app.InputImagePeriodicLabel = uilabel(app.PeriodicNoiseRemovalTab);
            app.InputImagePeriodicLabel.Text = 'Input Image';
            app.LoadImageInputPeriodicButton = uibutton(app.PeriodicNoiseRemovalTab, 'push');
            app.LoadImageInputPeriodicButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageInputPeriodicButtonPushed, true);
            app.LoadImageInputPeriodicButton.Text = 'Load Image';
            app.ExecuteOutputPeriodicButton = uibutton(app.PeriodicNoiseRemovalTab, 'push');
            app.ExecuteOutputPeriodicButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteOutputPeriodicButtonPushed, true);
            app.ExecuteOutputPeriodicButton.Text = 'Execute';
            app.ImageInputFreq = uiimage(app.PeriodicNoiseRemovalTab);
            app.ImageInputFreqLabel = uilabel(app.PeriodicNoiseRemovalTab);
            app.ImageInputFreqLabel.Text = 'Frequency Input';
            app.D0EditFieldLabel = uilabel(app.PeriodicNoiseRemovalTab);
            app.D0EditFieldLabel.Text = 'D0';
            app.D0EditField = uieditfield(app.PeriodicNoiseRemovalTab, 'numeric');
            app.D0EditField.Limits = [1 Inf];
            app.D0EditField.Value = 1;
            app.QvaluecontraharmonicLabel_5 = uilabel(app.PeriodicNoiseRemovalTab);
            app.QvaluecontraharmonicLabel_5.Text = 'Width';
            app.wValueEditField = uieditfield(app.PeriodicNoiseRemovalTab, 'numeric');
            app.wValueEditField.Value = 1;
            app.ImageOutputPeriodic = uiimage(app.PeriodicNoiseRemovalTab);
            app.InputImageNoiseLabel_3 = uilabel(app.PeriodicNoiseRemovalTab);
            app.InputImageNoiseLabel_3.Text = 'Output Image';
            app.ImageOutputFreq = uiimage(app.PeriodicNoiseRemovalTab);
            app.NoiseImageLabel_3 = uilabel(app.PeriodicNoiseRemovalTab);
            app.NoiseImageLabel_3.Text = 'Frequency Output';
            app.WienerMotionBlurTab = uitab(app.TabGroup);
            app.WienerMotionBlurTab.Title = 'Wiener Motion Blur';
            app.ImageInputBlur = uiimage(app.WienerMotionBlurTab);
            app.ImageOutputBlur = uiimage(app.WienerMotionBlurTab);
            app.InputImageBlurLabel = uilabel(app.WienerMotionBlurTab);
            app.InputImageBlurLabel.Text = 'Input Image';
            app.OutputImageBlurLabel = uilabel(app.WienerMotionBlurTab);
            app.OutputImageBlurLabel.Text = 'Output Image';
            app.LoadImageInputBlurButton = uibutton(app.WienerMotionBlurTab, 'push');
            app.LoadImageInputBlurButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageInputBlurButtonPushed, true);
            app.LoadImageInputBlurButton.Text = 'Load Image';
            app.ExecuteOutputBlurButton = uibutton(app.WienerMotionBlurTab, 'push');
            app.ExecuteOutputBlurButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteOutputBlurButtonPushed, true);
            app.ExecuteOutputBlurButton.Text = 'Execute';
            app.ImageBlur = uiimage(app.WienerMotionBlurTab);
            app.BlurredImageLabel = uilabel(app.WienerMotionBlurTab);
            app.BlurredImageLabel.Text = 'Blurred Image';
            app.ExecuteBlurButton = uibutton(app.WienerMotionBlurTab, 'push');
            app.ExecuteBlurButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteBlurButtonPushed, true);
            app.ExecuteBlurButton.Text = 'Blur Image';
            app.ThetaEditFieldLabel = uilabel(app.WienerMotionBlurTab);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditFieldBlur = uieditfield(app.WienerMotionBlurTab, 'numeric');
            app.QvaluecontraharmonicLabel_4 = uilabel(app.WienerMotionBlurTab);
            app.QvaluecontraharmonicLabel_4.Text = 'NSR';
            app.NSRValueEditFieldBlur = uieditfield(app.WienerMotionBlurTab, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.WienerMotionBlurTab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditFieldBlur = uieditfield(app.WienerMotionBlurTab, 'numeric');
            app.LengthEditFieldBlur.Value = 1;
            app.DeblurringTab = uitab(app.TabGroup);
            app.DeblurringTab.Title = 'Deblurring';
            app.ImageInputDeblur = uiimage(app.DeblurringTab);
            app.ImageOutputDeblur = uiimage(app.DeblurringTab);
            app.InputImageHighBoostLabel_2 = uilabel(app.DeblurringTab);
            app.InputImageHighBoostLabel_2.Text = 'Input Image';
            app.OutputImageHighBoostLabel_2 = uilabel(app.DeblurringTab);
            app.OutputImageHighBoostLabel_2.Text = 'Output Image';
            app.LoadImageDeblur = uibutton(app.DeblurringTab, 'push');
            app.LoadImageDeblur.ButtonPushedFcn = createCallbackFcn(app, @LoadImageDeblurButtonPushed, true);
            app.LoadImageDeblur.Text = 'Load Image';
            app.ExecuteDeblurButton = uibutton(app.DeblurringTab, 'push');
            app.ExecuteDeblurButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteDeblurButtonPushed, true);
            app.ExecuteDeblurButton.Text = 'Execute';
            app.CutoffFrequencyLabel_4 = uilabel(app.DeblurringTab);
            app.CutoffFrequencyLabel_4.Text = 'sigma gaussian blur';
            app.sigmaValueEditFieldDeblur = uieditfield(app.DeblurringTab, 'numeric');
            app.sigmaValueEditFieldDeblur.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end